classdef praticeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        AnalysisTab                  matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        DataTable                    matlab.ui.container.Tab
        UITable                      matlab.ui.control.Table
        SensorViewButtonGroup        matlab.ui.container.ButtonGroup
        ForestButton                 matlab.ui.control.RadioButton
        RoadButton                   matlab.ui.control.RadioButton
        CaveButton                   matlab.ui.control.RadioButton
        WaterfallButton              matlab.ui.control.RadioButton
        RangeSettingButtonGroup      matlab.ui.container.ButtonGroup
        ThisDayButton                matlab.ui.control.RadioButton
        This3DayButton               matlab.ui.control.RadioButton
        ThisWeekButton               matlab.ui.control.RadioButton
        PreviousPositionSliderLabel  matlab.ui.control.Label
        PreviousPositionSlider       matlab.ui.control.Slider
        ConnectionLampLabel          matlab.ui.control.Label
        ConnectionLamp               matlab.ui.control.Lamp
    end


    properties (Access = private)
        data = table;
        checkStatusTimer = timer;
        sensorActive = 'forest';
        data_min = 1.0;
        data_max = 2.0;
        lrangeActive = 3;
        rangeActive = 3;
        lprev = 0;
        prev = 0;
    end

    methods (Access = private)
    
        function processor_data(app,data)
            sensorSelected = ismember(app.data.tag, app.sensorActive) & (app.data.value >= app.data_min & app.data.value < app.data_max) & (app.data.timestamp < datetime('now') & app.data.timestamp >= (datetime('now')-days(app.rangeActive)));
            dataSelected = app.data.value(sensorSelected);
            timeSelected = app.data.timestamp(sensorSelected);
            update_ui(app,timeSelected,dataSelected);
        end
        
        function update_ui(app,dateSel,dataSel)
            if (isempty(dataSel))
                if (app.lrangeActive == 1) 
                    app.RangeSettingButtonGroup.SelectedObject = app.ThisDayButton;
                    app.rangeActive = 1;
                elseif (app.lrangeActive == 3) 
                    app.RangeSettingButtonGroup.SelectedObject = app.This3DayButton;
                    app.rangeActive = 3;
                elseif (app.lrangeActive == 7) 
                    app.RangeSettingButtonGroup.SelectedObject = app.ThisWeekButton;
                    app.rangeActive = 7;
                end
            end
            dateUI = cellstr(dateSel);
            dataUI = num2cell(dataSel);
            app.UITable.Data = [dateUI dataUI];
            plot(app.UIAxes,dateSel,dataSel,'-x','MarkerSize',3);
        end

        function checkStatusTimerCallbackFunction(app)
             load data.mat;
             app.data = datatable;
             app.ConnectionLamp.Color = 'blue';
             pause(50);
             app.ConnectionLamp.Color = 'green';
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            checkStatusTimerCallbackFunction(app)
            app.checkStatusTimer = timer('StartDelay', 4, 'Period', 500e-3, 'TasksToExecute', Inf, 'ExecutionMode', 'fixedRate');
            app.checkStatusTimer.TimerFcn = @(obj, event)checkStatusTimerCallbackFunction(app);
            processor_data(app,app.data)
        end

        % Selection changed function: RangeSettingButtonGroup
        function RangeSettingButtonGroupSelectionChanged(app, event)
            app.lrangeActive = app.rangeActive;
            selectedButton = app.RangeSettingButtonGroup.SelectedObject;
            if(selectedButton == app.ThisDayButton) app.rangeActive = 1;
            elseif(selectedButton == app.This3DayButton) app.rangeActive = 3;
            elseif(selectedButton == app.ThisWeekButton) app.rangeActive = 7;
            end
            processor_data(app,app.data)
        end

        % Selection changed function: SensorViewButtonGroup
        function SensorViewButtonGroupSelectionChanged(app, event)
            selectedButton = app.SensorViewButtonGroup.SelectedObject;
            if(selectedButton == app.ForestButton) app.sensorActive = 'forest';
            elseif(selectedButton == app.RoadButton) app.sensorActive = 'road';
            elseif(selectedButton == app.CaveButton) app.sensorActive = 'cave';
            elseif(selectedButton == app.WaterfallButton) app.sensorActive = 'waterfall';
            end
            processor_data(app,app.data)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.UIAxes = uiaxes(app.AnalysisTab);
            title(app.UIAxes, 'Dashboard')
            xlabel(app.UIAxes, 'Date/Time')
            ylabel(app.UIAxes, 'Value')
            app.DataTable = uitab(app.TabGroup);
            app.DataTable.Title = 'Data Table';
            app.UITable = uitable(app.DataTable);
            app.UITable.ColumnName = {'Date/Time'; 'Value'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.SensorViewButtonGroup = uibuttongroup(app.UIFigure);
            app.SensorViewButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SensorViewButtonGroupSelectionChanged, true);
            app.SensorViewButtonGroup.Title = 'Sensor View';
            app.ForestButton = uiradiobutton(app.SensorViewButtonGroup);
            app.ForestButton.Text = 'Forest';
            app.ForestButton.Value = true;
            app.RoadButton = uiradiobutton(app.SensorViewButtonGroup);
            app.RoadButton.Text = 'Road';
            app.CaveButton = uiradiobutton(app.SensorViewButtonGroup);
            app.CaveButton.Text = 'Cave';
            app.WaterfallButton = uiradiobutton(app.SensorViewButtonGroup);
            app.WaterfallButton.Text = 'Waterfall';
            app.RangeSettingButtonGroup = uibuttongroup(app.UIFigure);
            app.RangeSettingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RangeSettingButtonGroupSelectionChanged, true);
            app.RangeSettingButtonGroup.Title = 'Range Setting';
            app.ThisDayButton = uiradiobutton(app.RangeSettingButtonGroup);
            app.ThisDayButton.Text = 'This Day';
            app.This3DayButton = uiradiobutton(app.RangeSettingButtonGroup);
            app.This3DayButton.Text = 'This 3-Day';
            app.This3DayButton.Value = true;
            app.ThisWeekButton = uiradiobutton(app.RangeSettingButtonGroup);
            app.ThisWeekButton.Text = 'This Week';
            app.PreviousPositionSliderLabel = uilabel(app.UIFigure);
            app.PreviousPositionSliderLabel.Text = 'Previous Position';
            app.PreviousPositionSlider = uislider(app.UIFigure);
            app.PreviousPositionSlider.Limits = [-7 0];
            app.ConnectionLampLabel = uilabel(app.UIFigure);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = praticeApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end